classdef minimum_spacing_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MinimumGapCalculatorUIFigure    matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        WorstCaseStoppingScenarioLabel  matlab.ui.control.Label
        FollowingVehicleResponseLabel   matlab.ui.control.Label
        MinimumsafegapforselectedspeedsisTextAreaLabel  matlab.ui.control.Label
        MinimumsafegapforselectedspeedsisTextArea  matlab.ui.control.TextArea
        InitialgapmEditFieldLabel       matlab.ui.control.Label
        InitialgapmEditField            matlab.ui.control.NumericEditField
        collisionTextArea               matlab.ui.control.TextArea
        ResultsLabel                    matlab.ui.control.Label
        FollowertypeDropDown            matlab.ui.control.DropDown
        LeadertypeDropDown              matlab.ui.control.DropDown
        v0kmhEditFieldLabel             matlab.ui.control.Label
        leaderv0EditField               matlab.ui.control.NumericEditField
        MaxJerkms3Label                 matlab.ui.control.Label
        leaderMaxJerkEditField          matlab.ui.control.NumericEditField
        MaxBrakems2Label                matlab.ui.control.Label
        leaderMaxBrakeEditField         matlab.ui.control.NumericEditField
        v0kmhEditFieldLabel_2           matlab.ui.control.Label
        followerv0EditField             matlab.ui.control.NumericEditField
        Delay1sEditFieldLabel           matlab.ui.control.Label
        Delay1EditField                 matlab.ui.control.NumericEditField
        ComfJerkms3EditFieldLabel       matlab.ui.control.Label
        ComfJerkEditField               matlab.ui.control.NumericEditField
        ComfBrakems3EditFieldLabel      matlab.ui.control.Label
        ComfBrakeEditField              matlab.ui.control.NumericEditField
        a0ms2EditFieldLabel             matlab.ui.control.Label
        a0EditField                     matlab.ui.control.NumericEditField
        Delay2sEditFieldLabel           matlab.ui.control.Label
        Delay2EditField                 matlab.ui.control.NumericEditField
        MaxJerkms3EditFieldLabel        matlab.ui.control.Label
        followerMaxJerkEditField        matlab.ui.control.NumericEditField
        MaxBrakems2EditFieldLabel       matlab.ui.control.Label
        followerMaxBrakeEditField       matlab.ui.control.NumericEditField
        UIAxes_2                        matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        TypicalparametersLabel          matlab.ui.control.Label
        CustomparametersLabel           matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        TypicalparametersLabel_2        matlab.ui.control.Label
        CustomparametersLabel_2         matlab.ui.control.Label
        collisionDetailsTextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        leader % leading vehicle
        follower % follower vehicle
        leaderType = 'P';
        followerType = 'P';
        
%         plotLegend = {'leader', 'follower'}
        t = 0:0.01:15;
    end
    
    methods (Access = private)
        
        function [] = updatePlot(app)
            app.leader.brakeFullStop(app.t);
            app.follower.brakeFullStop(app.t);
            
            % Update drop down list
            [typeF, typeL] = app.translateVehType();
            app.FollowertypeDropDown.Value = typeF;
            app.LeadertypeDropDown.Value = typeL;
            
            % Plots
            ylim = [-max(app.leader.maxBrake, app.follower.maxBrake)-0.5, app.follower.maxAccel+0.5];
            plot(app.UIAxes, app.t, app.leader.at, 'LineWidth',1.5);
            grid(app.UIAxes, 'on')
            app.UIAxes.YLim = ylim;
            plot(app.UIAxes_2, app.t, app.follower.at, 'LineWidth',1.5);
            grid(app.UIAxes_2, 'on')
            app.UIAxes_2.YLim = ylim;
%             legend(app.UIAxes, app.plotLegend, 'Location', "southeast"); 
            
            % Numerical results
            [minGap, collTimeIdx, severity] = app.follower.computeFollowingMSS(app.leader);
            app.MinimumsafegapforselectedspeedsisTextArea.Value = sprintf('%0.1f m', minGap);
%             app.MinimumTimeheadwayTextArea.Value = sprintf('%0.1f s', minGap/app.follower.v0);
            if isempty(collTimeIdx)
                app.collisionTextArea.Value = 'No collision.';
                app.collisionTextArea.FontColor = 'k';
                app.collisionDetailsTextArea.Visible = 'off';
            else
                app.collisionTextArea.Value = sprintf('Collision.');
                app.collisionTextArea.FontColor = 'r';
                app.collisionDetailsTextArea.Visible = 'on';
                app.collisionDetailsTextArea.Value = sprintf('Severity: Delta v = %.1f km/h \nTime: t = %0.2fs', severity*3.6, app.t(collTimeIdx)');
            end
        end
        
        function [typeF, typeL] = translateVehType(app)
            switch app.followerType
                case 'P'
                    typeF = 'Passenger';
                case 'B'
                    typeF = 'Bus';
                case 'T'
                    typeF = 'Truck';
                case 'Custom'
                    typeF = 'Custom';
            end
            switch app.leaderType
                case 'P'
                    typeL = 'Passenger';
                case 'B'
                    typeL = 'Bus';
                case 'T'
                    typeL = 'Truck';
                case 'Custom'
                    typeL = 'Custom';
            end
        end
        
        function saturatedValue = checkUserValue(app, value, name)
        
            saturatedValue = value;
            
            switch name
                case 'comf jerk'
                    if value > app.followerMaxJerkEditField.Value
                        message = 'Comfortable jerk cannot be grater than maximum jerk. Setting comf jerk = max jerk';
                        saturatedValue = app.followerMaxJerkEditField.Value;
                    end
                case 'max jerk'
                    if value < app.ComfJerkEditField.Value
                        message = 'Maximum jerk cannot be smaller than comfortable jerk. Setting max jerk = comf jerk';
                        saturatedValue = app.ComfJerkEditField.Value;
                    end
                case 'comf brake'
                    if value > app.followerMaxBrakeEditField.Value
                        message = 'Comfortable brake cannot be grater than maximum brake. Setting comf brake = max brake';
                        saturatedValue = app.followerMaxBrakeEditField.Value;
                    end
                case 'max brake'
                    if value < app.ComfBrakeEditField.Value
                        message = 'Maximum brake cannot be smaller than comfortable brake. Setting max brake = comf brake';
                        saturatedValue = app.ComfBrakeEditField.Value;
                    end
            end
                        
            if saturatedValue~=value
                uialert(app.MinimumGapCalculatorUIFigure, message, 'Out of Bounds Value', 'Icon', "warning");
            else
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            v0 = 90; %[km/h]
            app.leader = Vehicle(app.leaderType, 'L', app.InitialgapmEditField.Value, v0/3.6, 0, 0);
            app.leaderv0EditField.Value = v0;
            app.leaderMaxJerkEditField.Value = app.leader.maxJerk;
            app.leaderMaxBrakeEditField.Value = app.leader.maxBrake;
            
            app.follower = Vehicle(app.followerType, 'F', 0, v0/3.6, app.Delay1EditField.Value, app.Delay2EditField.Value);
            app.followerv0EditField.Value = v0;
            app.a0EditField.Value = app.follower.maxAccel;
            app.ComfJerkEditField.Value = app.follower.comfJerk;
            app.ComfBrakeEditField.Value = app.follower.comfBrake;
            app.followerMaxJerkEditField.Value = app.follower.maxJerk;
            app.followerMaxBrakeEditField.Value = app.follower.maxBrake;
            
            app.updatePlot();
        end

        % Value changed function: InitialgapmEditField
        function InitialgapmEditFieldValueChanged(app, event)
            value = app.InitialgapmEditField.Value;
            app.leader.x0 = value;
            app.updatePlot();
        end

        % Value changed function: FollowertypeDropDown
        function FollowertypeDropDownValueChanged(app, event)
            value = app.FollowertypeDropDown.Value;
            switch value
                case 'Passenger'
                    type = 'P';
                case 'Bus'
                    type = 'B';
                case 'Truck'
                    type = 'T';
                case 'Custom'
                    return
            end
            app.followerType = type;
            app.follower = Vehicle(type, 'F', 0, app.follower.v0, app.follower.timeToBrake, app.follower.timeToEmergencyBrake);
            
            app.a0EditField.Value = app.follower.maxAccel;
            app.ComfJerkEditField.Value = app.follower.comfJerk;
            app.ComfBrakeEditField.Value = app.follower.comfBrake;
            app.followerMaxBrakeEditField.Value = app.follower.maxBrake;
            app.followerMaxJerkEditField.Value = app.follower.maxJerk;
            
            app.updatePlot();
        end

        % Value changed function: LeadertypeDropDown
        function LeadertypeDropDownValueChanged(app, event)
            value = app.LeadertypeDropDown.Value;
            switch value
                case 'Passenger'
                    type = 'P';
                case 'Bus'
                    type = 'B';
                case 'Truck'
                    type = 'T';
                case 'Custom'
                    return
            end
            app.leaderType = type;
            app.leader = Vehicle(type, 'L', app.InitialgapmEditField.Value, app.leader.v0, 0, 0);
            
            app.leaderMaxBrakeEditField.Value = app.leader.maxBrake;
            app.leaderMaxJerkEditField.Value = app.leader.maxJerk;
            
            app.updatePlot();
        end

        % Value changed function: leaderv0EditField
        function leaderv0EditFieldValueChanged(app, event)
            value = app.leaderv0EditField.Value;
            app.leader.v0 = value/3.6;
            app.leaderType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: leaderMaxJerkEditField
        function leaderMaxJerkEditFieldValueChanged(app, event)
            value = app.leaderMaxJerkEditField.Value;
            app.leader.maxJerk = value;
            app.leaderType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: leaderMaxBrakeEditField
        function leaderMaxBrakeEditFieldValueChanged(app, event)
            value = app.leaderMaxBrakeEditField.Value;
            app.leader.maxBrake = value;
            app.leaderType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: followerv0EditField
        function followerv0EditFieldValueChanged(app, event)
            value = app.followerv0EditField.Value;
            app.follower.v0 = value/3.6;
            app.updatePlot();
        end

        % Value changed function: Delay1EditField
        function Delay1EditFieldValueChanged(app, event)
            value = app.Delay1EditField.Value;
            app.follower.timeToBrake = value;
            app.updatePlot();
        end

        % Value changed function: ComfJerkEditField
        function ComfJerkEditFieldValueChanged(app, event)
            value = app.checkUserValue(app.ComfJerkEditField.Value, 'comf jerk');
            app.ComfJerkEditField.Value = value;
            app.follower.comfJerk = value;
            app.followerType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: ComfBrakeEditField
        function ComfBrakeEditFieldValueChanged(app, event)
            value = app.checkUserValue(app.ComfBrakeEditField.Value, 'comf brake');
            app.ComfBrakeEditField.Value = value;
            app.follower.comfBrake = value;
            app.followerType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: a0EditField
        function a0EditFieldValueChanged(app, event)
            value = app.a0EditField.Value;
            app.follower.a0 = value;
            app.followerType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: Delay2EditField
        function Delay2EditFieldValueChanged(app, event)
            value = app.Delay2EditField.Value;
            app.follower.timeToEmergencyBrake = value;
            app.updatePlot();
        end

        % Value changed function: followerMaxJerkEditField
        function followerMaxJerkEditFieldValueChanged(app, event)
            value = app.checkUserValue(app.followerMaxJerkEditField.Value, 'max jerk');
            app.followerMaxJerkEditField.Value = value;
            app.follower.maxJerk = value;
            app.followerType = 'Custom';
            app.updatePlot();
        end

        % Value changed function: followerMaxBrakeEditField
        function followerMaxBrakeEditFieldValueChanged(app, event)
            value = app.checkUserValue(app.followerMaxBrakeEditField.Value, 'max brake');
            app.followerMaxBrakeEditField.Value = value;
            app.follower.maxBrake = value;
            app.followerType = 'Custom';
            app.updatePlot();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            message = sprintf(['The leading vehicle starts with velocity v0 (currently set to %g km/h). It does emergency stopping with maximum ' ...
                'jerk (currently set to %g m/s^3) until it reaches maximum deceleration (currently set to %g m/s^2). Then It keeps ' ...
                'maximum deceleration until complete stop.'], app.leader.v0*3.6, app.leader.maxJerk, app.leader.maxBrake);
            uialert(app.MinimumGapCalculatorUIFigure, message, 'Worst Case Stopping Scenario', 'Icon', "info");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            message = sprintf(['The following vehicle starts with velocity v0 (currently set to %g km/h) and acceleration a0 ' ...
                '(currently set to %g m/s^2). It responds to the leader braking as follows:\n ' ...
                'After a time Delay 1 (currently set to %g s), the following vehicle realizes its leader is braking. At first, the following ' ...
                'vehicle does not know this is an emergency situation and applies a comfortable negative jerk (currently set at %g m/s^3) until it reaches ' ...
                'a comfortable deceleration (currently set at %g m/s^2). It takes the following vehicle another time interval Delta 2 ' ...
                '(currently set to %g s) to notice this is an emergency scenario. By then, it applies maximum negative jerk (currently set at %g m/s^3) ' ...
                'until it reaches its maximum deceleration (currently set at %g m/s^2). Finally, it keeps maximum deceleration until ' ...
                'complete stop.'], app.follower.v0*3.6, app.follower.maxAccel, app.follower.timeToBrake, app.follower.comfJerk, app.follower.comfBrake, ...
                app.follower.timeToEmergencyBrake, app.follower.maxJerk, app.follower.maxBrake);
            uialert(app.MinimumGapCalculatorUIFigure, message, 'Following Vehicle Response', 'Icon', "info");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MinimumGapCalculatorUIFigure = uifigure('Visible', 'off');
            app.MinimumGapCalculatorUIFigure.Name = 'Minimum Gap Calculator';
            app.UIAxes = uiaxes(app.MinimumGapCalculatorUIFigure);
            title(app.UIAxes, 'Leader Acceleration Profile')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'a [m/s^2]')
            app.WorstCaseStoppingScenarioLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.WorstCaseStoppingScenarioLabel.Text = 'Worst-Case Stopping Scenario';
            app.FollowingVehicleResponseLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.FollowingVehicleResponseLabel.Text = 'Following Vehicle Response';
            app.MinimumsafegapforselectedspeedsisTextAreaLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.MinimumsafegapforselectedspeedsisTextAreaLabel.Text = {'Minimum safe gap '; 'for selected speeds is:'};
            app.MinimumsafegapforselectedspeedsisTextArea = uitextarea(app.MinimumGapCalculatorUIFigure);
            app.MinimumsafegapforselectedspeedsisTextArea.Editable = 'off';
            app.InitialgapmEditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.InitialgapmEditFieldLabel.Text = 'Initial gap [m]';
            app.InitialgapmEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.InitialgapmEditField.Limits = [0 Inf];
            app.InitialgapmEditField.ValueChangedFcn = createCallbackFcn(app, @InitialgapmEditFieldValueChanged, true);
            app.InitialgapmEditField.Value = 50;
            app.collisionTextArea = uitextarea(app.MinimumGapCalculatorUIFigure);
            app.collisionTextArea.Editable = 'off';
            app.ResultsLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.ResultsLabel.Text = 'Results';
            app.FollowertypeDropDown = uidropdown(app.MinimumGapCalculatorUIFigure);
            app.FollowertypeDropDown.Items = {'Passenger', 'Bus', 'Truck', 'Custom'};
            app.FollowertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FollowertypeDropDownValueChanged, true);
            app.FollowertypeDropDown.Tooltip = {'set common parameters for different vehicle types'};
            app.FollowertypeDropDown.Value = 'Passenger';
            app.LeadertypeDropDown = uidropdown(app.MinimumGapCalculatorUIFigure);
            app.LeadertypeDropDown.Items = {'Passenger', 'Bus', 'Truck', 'Custom'};
            app.LeadertypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LeadertypeDropDownValueChanged, true);
            app.LeadertypeDropDown.Tooltip = {'set common parameters for different vehicle types'};
            app.LeadertypeDropDown.Value = 'Passenger';
            app.v0kmhEditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.v0kmhEditFieldLabel.Text = 'v0 [km/h]';
            app.leaderv0EditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.leaderv0EditField.Limits = [0 130];
            app.leaderv0EditField.ValueChangedFcn = createCallbackFcn(app, @leaderv0EditFieldValueChanged, true);
            app.MaxJerkms3Label = uilabel(app.MinimumGapCalculatorUIFigure);
            app.MaxJerkms3Label.Text = 'Max Jerk  [m/s^3]';
            app.leaderMaxJerkEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.leaderMaxJerkEditField.Limits = [5 80];
            app.leaderMaxJerkEditField.ValueChangedFcn = createCallbackFcn(app, @leaderMaxJerkEditFieldValueChanged, true);
            app.leaderMaxJerkEditField.Value = 5;
            app.MaxBrakems2Label = uilabel(app.MinimumGapCalculatorUIFigure);
            app.MaxBrakems2Label.Text = 'Max Brake [m/s^2]';
            app.leaderMaxBrakeEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.leaderMaxBrakeEditField.Limits = [1 10];
            app.leaderMaxBrakeEditField.ValueChangedFcn = createCallbackFcn(app, @leaderMaxBrakeEditFieldValueChanged, true);
            app.leaderMaxBrakeEditField.Value = 1;
            app.v0kmhEditFieldLabel_2 = uilabel(app.MinimumGapCalculatorUIFigure);
            app.v0kmhEditFieldLabel_2.Text = 'v0 [km/h]';
            app.followerv0EditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.followerv0EditField.Limits = [0 130];
            app.followerv0EditField.ValueChangedFcn = createCallbackFcn(app, @followerv0EditFieldValueChanged, true);
            app.Delay1sEditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.Delay1sEditFieldLabel.Text = 'Delay 1 [s]';
            app.Delay1EditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.Delay1EditField.Limits = [0 5];
            app.Delay1EditField.ValueChangedFcn = createCallbackFcn(app, @Delay1EditFieldValueChanged, true);
            app.Delay1EditField.Value = 1;
            app.ComfJerkms3EditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.ComfJerkms3EditFieldLabel.Text = 'Comf Jerk [m/s^3]';
            app.ComfJerkEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.ComfJerkEditField.Limits = [5 80];
            app.ComfJerkEditField.ValueChangedFcn = createCallbackFcn(app, @ComfJerkEditFieldValueChanged, true);
            app.ComfJerkEditField.Value = 5;
            app.ComfBrakems3EditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.ComfBrakems3EditFieldLabel.Text = 'Comf Brake[m/s^3]';
            app.ComfBrakeEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.ComfBrakeEditField.Limits = [1 10];
            app.ComfBrakeEditField.ValueChangedFcn = createCallbackFcn(app, @ComfBrakeEditFieldValueChanged, true);
            app.ComfBrakeEditField.Value = 1;
            app.a0ms2EditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.a0ms2EditFieldLabel.Text = 'a0 [m/s^2]';
            app.a0EditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.a0EditField.Limits = [1 10];
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @a0EditFieldValueChanged, true);
            app.a0EditField.Value = 1;
            app.Delay2sEditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.Delay2sEditFieldLabel.Text = 'Delay 2 [s]';
            app.Delay2EditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.Delay2EditField.Limits = [0 5];
            app.Delay2EditField.ValueChangedFcn = createCallbackFcn(app, @Delay2EditFieldValueChanged, true);
            app.Delay2EditField.Value = 1;
            app.MaxJerkms3EditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.MaxJerkms3EditFieldLabel.Text = 'Max Jerk [m/s^3]';
            app.followerMaxJerkEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.followerMaxJerkEditField.Limits = [5 80];
            app.followerMaxJerkEditField.ValueChangedFcn = createCallbackFcn(app, @followerMaxJerkEditFieldValueChanged, true);
            app.followerMaxJerkEditField.Value = 10;
            app.MaxBrakems2EditFieldLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.MaxBrakems2EditFieldLabel.Text = 'Max Brake [m/s^2]';
            app.followerMaxBrakeEditField = uieditfield(app.MinimumGapCalculatorUIFigure, 'numeric');
            app.followerMaxBrakeEditField.Limits = [1 10];
            app.followerMaxBrakeEditField.ValueChangedFcn = createCallbackFcn(app, @followerMaxBrakeEditFieldValueChanged, true);
            app.followerMaxBrakeEditField.Value = 1;
            app.UIAxes_2 = uiaxes(app.MinimumGapCalculatorUIFigure);
            title(app.UIAxes_2, 'Follower Acceleration Profile')
            xlabel(app.UIAxes_2, 't [s]')
            ylabel(app.UIAxes_2, 'a [m/s^2]')
            app.Button = uibutton(app.MinimumGapCalculatorUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'uialert_info.png';
            app.Button.Text = '';
            app.TypicalparametersLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.TypicalparametersLabel.Text = 'Typical parameters:';
            app.CustomparametersLabel = uilabel(app.MinimumGapCalculatorUIFigure);
            app.CustomparametersLabel.Text = 'Custom parameters:';
            app.Button_2 = uibutton(app.MinimumGapCalculatorUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'uialert_info.png';
            app.Button_2.Text = '';
            app.TypicalparametersLabel_2 = uilabel(app.MinimumGapCalculatorUIFigure);
            app.TypicalparametersLabel_2.Text = 'Typical parameters:';
            app.CustomparametersLabel_2 = uilabel(app.MinimumGapCalculatorUIFigure);
            app.CustomparametersLabel_2.Text = 'Custom parameters:';
            app.collisionDetailsTextArea = uitextarea(app.MinimumGapCalculatorUIFigure);
            app.collisionDetailsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.MinimumGapCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = minimum_spacing_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MinimumGapCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MinimumGapCalculatorUIFigure)
        end
    end
end